classdef MATEModelSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CarefullonlyoneandoneperlineLabel  matlab.ui.control.Label
        SoCdescriptionDropDown          matlab.ui.control.DropDown
        FPGAdescriptionDropDown_2Label  matlab.ui.control.Label
        SoCmodellist2columnononeTextArea  matlab.ui.control.TextArea
        SoCmodellist2columnononeLabel   matlab.ui.control.Label
        FPGAOpenButton                  matlab.ui.control.Button
        SoCShortmodeldescriptionLabel   matlab.ui.control.Label
        SoCHyperlink                    matlab.ui.control.Hyperlink
        FPGAHyperlink                   matlab.ui.control.Hyperlink
        FPGAShortmodeldescriptionLabel  matlab.ui.control.Label
        FPGAdescriptionDropDown         matlab.ui.control.DropDown
        FPGAdescriptionDropDownLabel    matlab.ui.control.Label
        FPGAmodellist2columnononeTextArea  matlab.ui.control.TextArea
        FPGAmodellist2columnononeTextAreaLabel  matlab.ui.control.Label
        MATEdescriptionDropDown         matlab.ui.control.DropDown
        MATEdescriptionDropDownLabel    matlab.ui.control.Label
        MATEShortmodeldescriptionLabel  matlab.ui.control.Label
        MATEmodellist2columnononeonperlineTextArea  matlab.ui.control.TextArea
        MATEmodellist2columnononeonperlineTextAreaLabel  matlab.ui.control.Label
        MATEHyperlink                   matlab.ui.control.Hyperlink
        SoCDropDown                     matlab.ui.control.DropDown
        MATESoCDropDownLabel            matlab.ui.control.Label
        SoCOpenButton                   matlab.ui.control.Button
        FPGADropDown                    matlab.ui.control.DropDown
        MATEFPGADropDownLabel           matlab.ui.control.Label
        MATEmodelselectorLabel          matlab.ui.control.Label
        MATEDropDown                    matlab.ui.control.DropDown
        MATEDropDownLabel               matlab.ui.control.Label
        MATEOpenButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %for main model
            x=app.MATEmodellist2columnononeonperlineTextArea.Value;  
            x1=cell(x);
            item1={};
            item2={};
            for i=1:size(x1,1)
                txt=x1{i,1};
                loc=strfind(txt,',');
                item1{i}=deblank(txt(1:loc-1));
                item2{i}=deblank(txt(loc+1:end));
            end
            % populate models
            app.MATEDropDown.Items = item1;
            app.MATEdescriptionDropDown.Items=item2;
            app.MATEShortmodeldescriptionLabel.Text=app.MATEdescriptionDropDown.Items(1);

            %for FPGA models
            x=app.FPGAmodellist2columnononeTextArea.Value;  
            x1=cell(x);
            item1={};
            item2={};
            for i=1:size(x1,1)
                txt=x1{i,1};
                loc=strfind(txt,',');
                item1{i}=deblank(txt(1:loc-1));
                item2{i}=deblank(txt(loc+1:end));
            end
            % populate models
            app.FPGADropDown.Items = item1;
            app.FPGAdescriptionDropDown.Items=item2;

            app.FPGAShortmodeldescriptionLabel.Text=app.FPGAdescriptionDropDown.Items(1);

            %for SoC models
            x=app.SoCmodellist2columnononeTextArea.Value;  
            x1=cell(x);
            item1={};
            item2={};
            for i=1:size(x1,1)
                txt=x1{i,1};
                loc=strfind(txt,',');
                if isempty(loc)
                    item1{i}='n/a';
                    item2{i}='n/a';
                else

                    item1{i}=deblank(txt(1:loc-1));
                    item2{i}=deblank(txt(loc+1:end));
                end
            end
            % populate models
            app.SoCDropDown.Items = item1;
            app.SoCdescriptionDropDown.Items=item2;

            app.SoCShortmodeldescriptionLabel.Text=app.SoCdescriptionDropDown.Items(1);
        end

        % Button pushed function: MATEOpenButton
        function MATEOpenButtonPushed(app, event)
            x=app.MATEDropDown.Value;
        
            open_system(x);
            x = replace(x,'.slx','');
            x2= [x '.html'];
            %open(x2);
            % app.MATEHyperlink.Text=x;
            % app.MATEHyperlink.URL=x2;
            
        end

        % Clicked callback: MATEDropDown
        function MATEDropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Callback function: MATEHyperlink
        function MATEHyperlinkClicked(app, event)
            
        end

        % Value changed function: MATEDropDown
        function MATEDropDownValueChanged(app, event)
            x = app.MATEDropDown.Value;
            ix = app.MATEDropDown.ValueIndex;
            x = replace(x,'.slx','');
            x2= [x '.html'];
            x3= ['Help for ' x ];
            %exist(x2,'file')
            if exist(x2,'file')
                app.MATEHyperlink.Text=x3;
                app.MATEHyperlink.URL=x2;
            else
                app.MATEHyperlink.Text='No help page available';
                app.MATEHyperlink.URL='www.superbadopal.com';
            end
            app.MATEShortmodeldescriptionLabel.Text=app.MATEdescriptionDropDown.Items(ix);


        end

        % Drop down opening function: FPGADropDown
        function FPGADropDownOpening(app, event)
            
        end

        % Value changed function: FPGADropDown
        function FPGADropDownValueChanged(app, event)
            %value = app.FPGADropDown.Value;
            x = app.FPGADropDown.Value;
            ix = app.FPGADropDown.ValueIndex;
            x = replace(x,'.slx','');
            x2= [x '.html'];
            x3= ['Help for ' x ];
            %exist(x2,'file');
            if exist(x2,'file')
                app.FPGAHyperlink.Text=x3;
                app.FPGAHyperlink.URL=x2;
            else
                app.FPGAHyperlink.Text='No help page available';
                app.FPGAHyperlink.URL='www.superbadopal.com';
            end
            app.FPGAShortmodeldescriptionLabel.Text=app.FPGAdescriptionDropDown.Items(ix);
            
        end

        % Button pushed function: FPGAOpenButton
        function FPGAOpenButtonPushed2(app, event)
            x=app.FPGADropDown.Value;    
            open_system(x);
            
        end

        % Value changed function: SoCDropDown
        function SoCDropDownValueChanged(app, event)
            value = app.SoCDropDown.Value;
             x = app.SoCDropDown.Value;
            ix = app.SoCDropDown.ValueIndex;
            x = replace(x,'.slx','');
            x2= [x '.html'];
            x3= ['Help for ' x ];
            %exist(x2,'file');
            if exist(x2,'file')
                app.SoCHyperlink.Text=x3;
                app.SoCHyperlink.URL=x2;
            else
                app.SoCHyperlink.Text='No help page available';
                app.SoCHyperlink.URL='www.superbadopal.com';
            end
            app.SoCShortmodeldescriptionLabel.Text=app.SoCdescriptionDropDown.Items(ix);
            
        end

        % Button pushed function: SoCOpenButton
        function SoCOpenButtonPushed(app, event)
            x=app.SoCDropDown.Value;    
            open_system(x);
            
        end

        % Callback function: SoCHyperlink
        function SoCHyperlinkClicked(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MATEOpenButton = uibutton(app.UIFigure, 'push');
            app.MATEOpenButton.ButtonPushedFcn = createCallbackFcn(app, @MATEOpenButtonPushed, true);
            app.MATEOpenButton.Text = 'Open';
            app.MATEDropDownLabel = uilabel(app.UIFigure);
            app.MATEDropDownLabel.Text = 'MATE';
            app.MATEDropDown = uidropdown(app.UIFigure);
            app.MATEDropDown.Items = {'MATE_IEEE13Node.slx', 'MATE_IEEE123Node.slx', 'MATE_9LevelDrive_LowHarmonicRectifier.slx', 'Option 2', 'Option 3', 'Option 4'};
            app.MATEDropDown.ValueChangedFcn = createCallbackFcn(app, @MATEDropDownValueChanged, true);
            app.MATEDropDown.ClickedFcn = createCallbackFcn(app, @MATEDropDownClicked, true);
            app.MATEDropDown.Value = 'MATE_IEEE13Node.slx';
            app.MATEmodelselectorLabel = uilabel(app.UIFigure);
            app.MATEmodelselectorLabel.Text = 'MATE model selector';
            app.MATEFPGADropDownLabel = uilabel(app.UIFigure);
            app.MATEFPGADropDownLabel.Text = 'MATE FPGA';
            app.FPGADropDown = uidropdown(app.UIFigure);
            app.FPGADropDown.Items = {'MATE_TrapezoidalRule.slx', 'MATE_PejovicOverSamplingInverter.slx', 'Option 3', 'Option 4'};
            app.FPGADropDown.DropDownOpeningFcn = createCallbackFcn(app, @FPGADropDownOpening, true);
            app.FPGADropDown.ValueChangedFcn = createCallbackFcn(app, @FPGADropDownValueChanged, true);
            app.FPGADropDown.Value = 'MATE_TrapezoidalRule.slx';
            app.SoCOpenButton = uibutton(app.UIFigure, 'push');
            app.SoCOpenButton.ButtonPushedFcn = createCallbackFcn(app, @SoCOpenButtonPushed, true);
            app.SoCOpenButton.Text = 'Open';
            app.MATESoCDropDownLabel = uilabel(app.UIFigure);
            app.MATESoCDropDownLabel.Text = 'MATE SoC';
            app.SoCDropDown = uidropdown(app.UIFigure);
            app.SoCDropDown.Items = {'MATE_HDLsolver_myFirstCircuit.slx', 'MATE_HDLsolver_mySecondCircuit.slx ', 'MATE_HDLsolver_my4thCircuit.slx', 'MATE_HDLsolver_my5thcircuitPMSMdrive.slx'};
            app.SoCDropDown.ValueChangedFcn = createCallbackFcn(app, @SoCDropDownValueChanged, true);
            app.SoCDropDown.Value = 'MATE_HDLsolver_myFirstCircuit.slx';
            app.MATEHyperlink = uihyperlink(app.UIFigure);
            app.MATEHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @MATEHyperlinkClicked, true);
            app.MATEHyperlink.Text = 'Model Help';
            app.MATEmodellist2columnononeonperlineTextAreaLabel = uilabel(app.UIFigure);
            app.MATEmodellist2columnononeonperlineTextAreaLabel.Text = 'MATE model list  2 column on , one; on , per line';
            app.MATEmodellist2columnononeonperlineTextArea = uitextarea(app.UIFigure);
            app.MATEmodellist2columnononeonperlineTextArea.Value = {'MATE_IEEE13Node.slx , IEEE 13 node model showing the accuracy of compensated stubline for decoupling;'; 'MATE_IEEE123Node.slx , IEEE 123 node model decoupled into 4 subsysterms using compensated stublines;'; 'MATE_9LevelDrive_LowHarmonicRectifier.slx, High-Power Low harmonic drive using feeder-side multi-winding transformer and 9-level AC inverter;'; 'MMC_200Cell_WB_Par_Ccode.slx, Full MMC-HVDC system - 200 cell per arm - DPL line- Simulink-Coder ready;'; 'SImScape_Bipolarhvdc_Par2.slx, Full 12-pulse bipolar HVDC system- DPL line- Simulink-Coder ready;'; 'MATE_2level_DriveOversampling.slx , 3-phase 2-level inverter drive- Oversampling-inverter with 4us deadtime- high-impedance- rectification- CPU Ts=30us;                                  '; 'MATE_3level_DriveOversampling.slx , 3-phase 3-level NPC inverter drive- Oversampling-inverter with 2us deadtime- high-impedance- rectification- CPU Ts=30us;'; 'SimScapeMATE_InductionMachine.slx , MATE constant admittance induction machine model in a AC small network   ;'; 'MATE_SynchronousMachine.slx, Constant admittance synchronous machine model with thyristor rectifier for excitation circuit (rectifier in SimScape- requires 2 iterations)  ;              '; 'MATE_SynchronousMachineV2.slx , Constant admittance synchronous machine model with thyristor rectifier for excitation circuit (rectifier in MATE state-space solver- 1 iteration only) ;                  '; 'SimScape_StaticVARCompensator.slx , Realistic Static Var compensator model (available on request);                               '; 'Simscape_IEEE123_oneThird.slx , state-space MATE grouping in IEEE 123 node system- aims to accelerate large power systems by reducing the size of the Jacobian Matrix- under development ;            '};
            app.MATEShortmodeldescriptionLabel = uilabel(app.UIFigure);
            app.MATEShortmodeldescriptionLabel.Text = 'Short model description';
            app.MATEdescriptionDropDownLabel = uilabel(app.UIFigure);
            app.MATEdescriptionDropDownLabel.Text = 'MATE description';
            app.MATEdescriptionDropDown = uidropdown(app.UIFigure);
            app.FPGAmodellist2columnononeTextAreaLabel = uilabel(app.UIFigure);
            app.FPGAmodellist2columnononeTextAreaLabel.Text = 'FPGA model list  2 column on , one;';
            app.FPGAmodellist2columnononeTextArea = uitextarea(app.UIFigure);
            app.FPGAmodellist2columnononeTextArea.Value = {'MATE_PMSMDynamicSwitches.slx , MATE Simulation case 1: PMSM drive demo with correct DC link current (to be compared with Mathworks demo with wrong DC current);                                         '; 'MATE_PejovicOverSamplingInverter.slx, under developement- oversampled Compensated Pejovic inverters ;             '; 'MATE_Pejovic_inverter.slx , Pejovic inverter compensation- comparison with Simscape model and standrad uncompensated Dynamic swiches;                                                 '; 'MATE_SwitchedReluctanceMotor64Drive.slx , Constant admittance 6/4 SRM motor drive- High impedance capable Switching function- HDL-Coder ready   ;   '; 'MATE_TrapezoidalRule.slx, Induction stovetop MathWorks demo made using MATE Pejovic element with trapezoidal rule of integration. Runs at 1us with Oversampling capable MATE inverter with 10 ns resolution;                        '; 'MATEInductionMachineDTC.slx , Direct torque controlled induction machine- Single mode models- HDL-Coder ready;'; 'MATE_PermanentMagnetSynchronousMachine.slx , PMSM Drive with AC Rectifier- 12kHz PWM- dead-time and high-impedance supported.;'};
            app.FPGAdescriptionDropDownLabel = uilabel(app.UIFigure);
            app.FPGAdescriptionDropDownLabel.Text = 'FPGA description';
            app.FPGAdescriptionDropDown = uidropdown(app.UIFigure);
            app.FPGAShortmodeldescriptionLabel = uilabel(app.UIFigure);
            app.FPGAShortmodeldescriptionLabel.Text = 'Short model description';
            app.FPGAHyperlink = uihyperlink(app.UIFigure);
            app.FPGAHyperlink.Text = 'Model Help';
            app.SoCHyperlink = uihyperlink(app.UIFigure);
            app.SoCHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @SoCHyperlinkClicked, true);
            app.SoCHyperlink.Text = 'Model Help';
            app.SoCShortmodeldescriptionLabel = uilabel(app.UIFigure);
            app.SoCShortmodeldescriptionLabel.Text = 'Short model description';
            app.FPGAOpenButton = uibutton(app.UIFigure, 'push');
            app.FPGAOpenButton.ButtonPushedFcn = createCallbackFcn(app, @FPGAOpenButtonPushed2, true);
            app.FPGAOpenButton.Text = 'Open';
            app.SoCmodellist2columnononeLabel = uilabel(app.UIFigure);
            app.SoCmodellist2columnononeLabel.Text = 'SoC model list  2 column on , one;';
            app.SoCmodellist2columnononeTextArea = uitextarea(app.UIFigure);
            app.SoCmodellist2columnononeTextArea.Value = {'MATE_HDLsolver_myFirstCircuit.slx, Buck converter for SoC FPGA simulation;                       '; 'MATE_HDLsolver_mySecondCircuit.slx , Boost converter for SoC FPGA simulation ;'; 'MATE_HDLsolver_my4thCircuit.slx , Buck converter with transformer for SoC FPGA simulation   ;                            '; 'MATE_HDLsolver_my5thcircuitPMSMdrive.slx , Boost converter with PMSM motor drive- 2 SoCs for FPGA simulation;'};
            app.FPGAdescriptionDropDown_2Label = uilabel(app.UIFigure);
            app.FPGAdescriptionDropDown_2Label.Text = 'FPGA description';
            app.SoCdescriptionDropDown = uidropdown(app.UIFigure);
            app.CarefullonlyoneandoneperlineLabel = uilabel(app.UIFigure);
            app.CarefullonlyoneandoneperlineLabel.Text = 'Carefull: only one ,  and one ; per line !!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MATEModelSelector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end